classdef ProjectController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DrivingControlsLabel      matlab.ui.control.Label
        ThetaPosLabel             matlab.ui.control.Label
        YPosLabel                 matlab.ui.control.Label
        XPosLabel                 matlab.ui.control.Label
        BackUltraSonicLabel       matlab.ui.control.Label
        RightUltrasonicLabel      matlab.ui.control.Label
        FrontUltraSonicLabel      matlab.ui.control.Label
        LeftUltrasonicLabel       matlab.ui.control.Label
        PitchArmSlider            matlab.ui.control.Slider
        PitchArmSliderLabel       matlab.ui.control.Label
        YawArmSlider              matlab.ui.control.Slider
        YawArmSliderLabel         matlab.ui.control.Label
        RollArmSlider             matlab.ui.control.Slider
        RollArmSliderLabel        matlab.ui.control.Label
        ZArmSlider                matlab.ui.control.Slider
        ZArmSliderLabel           matlab.ui.control.Label
        YArmSlider                matlab.ui.control.Slider
        YArmSliderLabel           matlab.ui.control.Label
        XArmSlider                matlab.ui.control.Slider
        XArmSliderLabel           matlab.ui.control.Label
        SendThetaButton           matlab.ui.control.Button
        SendYButton               matlab.ui.control.Button
        SendXButton               matlab.ui.control.Button
        ThetaEntryEditField       matlab.ui.control.NumericEditField
        ThetaEntryEditFieldLabel  matlab.ui.control.Label
        YEntryEditField           matlab.ui.control.NumericEditField
        YEntryEditFieldLabel      matlab.ui.control.Label
        XEntryEditField           matlab.ui.control.NumericEditField
        XEntryEditFieldLabel      matlab.ui.control.Label
        GripperButtonGroup        matlab.ui.container.ButtonGroup
        OpenButton                matlab.ui.control.ToggleButton
        CloseButton               matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        serialObj % Description
    end
    
    methods (Access = private)
        
        function sendServoValue(app, joint, degrees)
            
            %degrees is a number, maybe int maybe float
            degrees = round(degrees);
            command = sprintf("%s%d\n", joint, degrees);
            
            fprintf("Sending: %s\n", command);
            writeline(app.serialObj, command);
            
        end
        
        function serialObjCallback(app, serialObj, ~)
            message = strtrim(readLine(serialObj));
            messageArray = split(message);
           
            if strcmpi(messageArray(1), "l")
                app.LeftUltrasonicLabel.Text = sprintf("Left Ultrasonic = %d", messageArray(2:end));
            end
            
            if strcmpi(messageArray(1), "r")
                app.RightUltrasonicLabel.Text = sprintf("Right Ultrasonic = %d", messageArray(2:end));
            end
            
            if strcmpi(messageArray(1), "f")
                app.FrontUltraSonicLabel.Text = sprintf("Front Ultrasonic = %d", messageArray(2:end));
            end
            
            if strcmpi(messageArray(1), "b")
                app.BackUltraSonicLabel.Text = sprintf("Back Ultrasonic = %d", messageArray(2:end));
            end
            
            if strcmpi(messageArray(1), "x")
                app.XPosLabel.Text = sprintf("Back Ultrasonic = %d", messageArray(2:end));
            end
            
            if strcmpi(messageArray(1), "y")
                app.YPosLabel.Text = sprintf("Back Ultrasonic = %d", messageArray(2:end));
            end
            
            if strcmpi(messageArray(1), "t")
                app.ThetaPosLabel.Text = sprintf("Back Ultrasonic = %d", messageArray(2:end));
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clc;
        app.serialObj = serialport("COM5", 9600);
        flush(app.serialObj);
        configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
        end

        % Callback function
        function XEntryEditFieldValueChanged(app, event)
          
        end

        % Value changed function: XArmSlider
        function XArmSliderValueChanged(app, event)
            valueXarm = app.XArmSlider.Value;
            
            app.sendServoValue("1", valueXarm);
        end

        % Value changing function: XArmSlider
        function XArmSliderValueChanging(app, event)
         
        end

        % Value changed function: YArmSlider
        function YArmSliderValueChanged(app, event)
            valueYarm = app.YArmSlider.Value;
            
            app.sendServoValue("2", valueYarm);
        end

        % Value changing function: YArmSlider
        function YArmSliderValueChanging(app, event)
           
        end

        % Value changed function: ZArmSlider
        function ZArmSliderValueChanged(app, event)
            valueZarm = app.ZArmSlider.Value;
            
            app.sendServoValue("3", valueZarm);
        end

        % Value changing function: ZArmSlider
        function ZArmSliderValueChanging(app, event)
        
        end

        % Value changed function: RollArmSlider
        function RollArmSliderValueChanged(app, event)
            valueRoll = app.RollArmSlider.Value;
            
            app.sendServoValue("4", valueRoll);
        end

        % Value changing function: RollArmSlider
        function RollArmSliderValueChanging(app, event)
            
        end

        % Value changed function: YawArmSlider
        function YawArmSliderValueChanged(app, event)
            valueYaw = app.YawArmSlider.Value;
            
            app.sendServoValue("5", valueYaw);
        end

        % Value changing function: YawArmSlider
        function YawArmSliderValueChanging(app, event)
            
        end

        % Value changed function: PitchArmSlider
        function PitchArmSliderValueChanged(app, event)
            valuePitch = app.PitchArmSlider.Value;
            
            app.sendServoValue("6", valuePitch);
        end

        % Value changing function: PitchArmSlider
        function PitchArmSliderValueChanging(app, event)
           
        end

        % Button pushed function: SendYButton
        function SendYButtonPushed(app, event)
            valueY = (app.YEntryEditField.Value);
            app.sendServoValue('y', valueY);
        end

        % Button pushed function: SendXButton
        function SendXButtonPushed(app, event)
            valueX = (app.XEntryEditField.Value);
            app.sendServoValue('x', valueX);
        end

        % Button pushed function: SendThetaButton
        function SendThetaButtonPushed(app, event)
            valueT = (app.ThetaEntryEditField.Value);
            app.sendServoValue('t', valueT);
        end

        % Value changed function: ThetaEntryEditField
        function ThetaEntryEditFieldValueChanged(app, event)
            %valueT = app.ThetaEntryEditField.Value;
        end

        % Value changed function: YEntryEditField
        function YEntryEditFieldValueChanged(app, event)
            %valueY = app.YEntryEditField.Value;
        end

        % Value changed function: XEntryEditField
        function XEntryEditFieldValueChanged2(app, event)
            %valueX = app.XEntryEditField.Value;
        end

        % Selection changed function: GripperButtonGroup
        function GripperButtonGroupSelectionChanged(app, event)
            selectedButton = app.GripperButtonGroup.SelectedObject;
            if selectedButton == app.CloseButton
                writeline(app.serialObj, "c\n");
            end
            if selectedButton == app.OpenButton
                writeline(app.serialObj, "o\n");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GripperButtonGroup = uibuttongroup(app.UIFigure);
            app.GripperButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GripperButtonGroupSelectionChanged, true);
            app.GripperButtonGroup.Title = 'Gripper';
            app.CloseButton = uitogglebutton(app.GripperButtonGroup);
            app.CloseButton.Text = 'Close';
            app.CloseButton.Value = true;
            app.OpenButton = uitogglebutton(app.GripperButtonGroup);
            app.OpenButton.Text = 'Open';
            app.XEntryEditFieldLabel = uilabel(app.UIFigure);
            app.XEntryEditFieldLabel.Text = 'X Entry';
            app.XEntryEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEntryEditField.ValueChangedFcn = createCallbackFcn(app, @XEntryEditFieldValueChanged2, true);
            app.YEntryEditFieldLabel = uilabel(app.UIFigure);
            app.YEntryEditFieldLabel.Text = 'Y Entry';
            app.YEntryEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEntryEditField.ValueChangedFcn = createCallbackFcn(app, @YEntryEditFieldValueChanged, true);
            app.ThetaEntryEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEntryEditFieldLabel.Text = 'Theta Entry';
            app.ThetaEntryEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEntryEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEntryEditFieldValueChanged, true);
            app.SendXButton = uibutton(app.UIFigure, 'push');
            app.SendXButton.ButtonPushedFcn = createCallbackFcn(app, @SendXButtonPushed, true);
            app.SendXButton.Text = 'Send X';
            app.SendYButton = uibutton(app.UIFigure, 'push');
            app.SendYButton.ButtonPushedFcn = createCallbackFcn(app, @SendYButtonPushed, true);
            app.SendYButton.Text = 'Send Y';
            app.SendThetaButton = uibutton(app.UIFigure, 'push');
            app.SendThetaButton.ButtonPushedFcn = createCallbackFcn(app, @SendThetaButtonPushed, true);
            app.SendThetaButton.Text = 'Send Theta';
            app.XArmSliderLabel = uilabel(app.UIFigure);
            app.XArmSliderLabel.Text = 'X Arm';
            app.XArmSlider = uislider(app.UIFigure);
            app.XArmSlider.Limits = [0 180];
            app.XArmSlider.ValueChangedFcn = createCallbackFcn(app, @XArmSliderValueChanged, true);
            app.XArmSlider.ValueChangingFcn = createCallbackFcn(app, @XArmSliderValueChanging, true);
            app.YArmSliderLabel = uilabel(app.UIFigure);
            app.YArmSliderLabel.Text = 'Y Arm';
            app.YArmSlider = uislider(app.UIFigure);
            app.YArmSlider.Limits = [0 180];
            app.YArmSlider.ValueChangedFcn = createCallbackFcn(app, @YArmSliderValueChanged, true);
            app.YArmSlider.ValueChangingFcn = createCallbackFcn(app, @YArmSliderValueChanging, true);
            app.ZArmSliderLabel = uilabel(app.UIFigure);
            app.ZArmSliderLabel.Text = 'Z Arm';
            app.ZArmSlider = uislider(app.UIFigure);
            app.ZArmSlider.Limits = [0 180];
            app.ZArmSlider.ValueChangedFcn = createCallbackFcn(app, @ZArmSliderValueChanged, true);
            app.ZArmSlider.ValueChangingFcn = createCallbackFcn(app, @ZArmSliderValueChanging, true);
            app.RollArmSliderLabel = uilabel(app.UIFigure);
            app.RollArmSliderLabel.Text = 'Roll Arm';
            app.RollArmSlider = uislider(app.UIFigure);
            app.RollArmSlider.Limits = [0 180];
            app.RollArmSlider.ValueChangedFcn = createCallbackFcn(app, @RollArmSliderValueChanged, true);
            app.RollArmSlider.ValueChangingFcn = createCallbackFcn(app, @RollArmSliderValueChanging, true);
            app.YawArmSliderLabel = uilabel(app.UIFigure);
            app.YawArmSliderLabel.Text = 'Yaw Arm';
            app.YawArmSlider = uislider(app.UIFigure);
            app.YawArmSlider.Limits = [0 180];
            app.YawArmSlider.ValueChangedFcn = createCallbackFcn(app, @YawArmSliderValueChanged, true);
            app.YawArmSlider.ValueChangingFcn = createCallbackFcn(app, @YawArmSliderValueChanging, true);
            app.PitchArmSliderLabel = uilabel(app.UIFigure);
            app.PitchArmSliderLabel.Text = 'Pitch Arm';
            app.PitchArmSlider = uislider(app.UIFigure);
            app.PitchArmSlider.Limits = [0 180];
            app.PitchArmSlider.ValueChangedFcn = createCallbackFcn(app, @PitchArmSliderValueChanged, true);
            app.PitchArmSlider.ValueChangingFcn = createCallbackFcn(app, @PitchArmSliderValueChanging, true);
            app.LeftUltrasonicLabel = uilabel(app.UIFigure);
            app.LeftUltrasonicLabel.Text = 'Left Ultrasonic = ';
            app.FrontUltraSonicLabel = uilabel(app.UIFigure);
            app.FrontUltraSonicLabel.Text = 'Front UltraSonic = ';
            app.RightUltrasonicLabel = uilabel(app.UIFigure);
            app.RightUltrasonicLabel.Text = 'Right Ultrasonic = ';
            app.BackUltraSonicLabel = uilabel(app.UIFigure);
            app.BackUltraSonicLabel.Text = 'Back UltraSonic = ';
            app.XPosLabel = uilabel(app.UIFigure);
            app.XPosLabel.Text = 'X-pos = ';
            app.YPosLabel = uilabel(app.UIFigure);
            app.YPosLabel.Text = 'Y-pos = ';
            app.ThetaPosLabel = uilabel(app.UIFigure);
            app.ThetaPosLabel.Text = 'Theta-pos = ';
            app.DrivingControlsLabel = uilabel(app.UIFigure);
            app.DrivingControlsLabel.Text = 'Driving Controls';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end