classdef RobotControllerSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StopButton                     matlab.ui.control.Button
        RotationVelocityGauge          matlab.ui.control.Gauge
        RotationVelocityGaugeLabel     matlab.ui.control.Label
        RightLeftVelocityGauge         matlab.ui.control.LinearGauge
        RightLeftVelocityGaugeLabel    matlab.ui.control.Label
        ForwardBackwardsVelocityGauge  matlab.ui.control.LinearGauge
        ForwardBackwardsVelocityGaugeLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        serialObj;
        y;
        x;
        t;
        d;
        R;
    end
    
    methods (Access = private)      
        function makeCommandsInRange(app)
            if app.y > 255
                app.y = 255;
            end
            if app.x > 255
                app.x = 255;
            end
            if app.t > 255
                app.t = 255;
            end
            if app.y < -255
                app.y = -255;
            end
            if app.x < -255
                app.x = -255;
            end
            if app.t < -255
                app.t = -255;
            end
        end
        function updateGauges(app)
            app.ForwardBackwardsVelocityGauge.Value = app.y*100/255;
            app.RightLeftVelocityGauge.Value = app.x*100/255;
            app.RotationVelocityGauge.Value = -app.t*100/255;
        end
        function motorValues = calculateMotorValues(app)
            motorValues = (1/app.R) * [1, 1, app.d; 1, -1, -app.d; 1, -1, app.d; 1, 1, -app.d] * [app.y; app.t; -app.x];
        end
        function sendMotorValues(app)
            motorValues = app.calculateMotorValues();
            if max(motorValues) > 255
                unitVector = motorValues ./ max(motorValues);
                motorValues = unitVector * 255;
            end
            command1 = strcat("1", string(motorValues(1)), "\n");
            command2 = strcat("2", string(motorValues(2)), "\n");
            command3 = strcat("3", string(motorValues(3)), "\n");
            command4 = strcat("4", string(motorValues(4)), "\n");
            writeline(app.serialObj, command1);
            writeline(app.serialObj, command2);
            writeline(app.serialObj, command3);
            writeline(app.serialObj, command4);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x = 0;
            app.y = 0;
            app.t = 0;
            app.d = 10.429;
            app.R = 6.649/2;
            clc;
            app.serialObj = serialport("COM5", 9600);
            flush(app.serialObj);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'w'
                    app.y = app.y + 25;
                case 's'
                    app.y = app.y - 25;
                case 'd'
                    app.x = app.x + 25;
                case 'a'
                    app.x = app.x - 25;
                case 'e'
                    app.t = app.t - 25;
                case 'q'
                    app.t = app.t + 25;
            end
            app.makeCommandsInRange();
            app.updateGauges();
            app.sendMotorValues();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            writeline(app.serialObj, "10\n");
            writeline(app.serialObj, "20\n");
            writeline(app.serialObj, "30\n");
            writeline(app.serialObj, "40\n");
            app.x = 0;
            app.y = 0;
            app.t = 0;
            updateGauges(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ForwardBackwardsVelocityGaugeLabel = uilabel(app.UIFigure);
            app.ForwardBackwardsVelocityGaugeLabel.Text = 'Forward/Backwards Velocity';
            app.ForwardBackwardsVelocityGauge = uigauge(app.UIFigure, 'linear');
            app.ForwardBackwardsVelocityGauge.Limits = [-100 100];
            app.RightLeftVelocityGaugeLabel = uilabel(app.UIFigure);
            app.RightLeftVelocityGaugeLabel.Text = 'Right/Left Velocity';
            app.RightLeftVelocityGauge = uigauge(app.UIFigure, 'linear');
            app.RightLeftVelocityGauge.Limits = [-100 100];
            app.RotationVelocityGaugeLabel = uilabel(app.UIFigure);
            app.RotationVelocityGaugeLabel.Text = 'Rotation Velocity';
            app.RotationVelocityGauge = uigauge(app.UIFigure, 'circular');
            app.RotationVelocityGauge.Limits = [-100 100];
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControllerSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end